classdef Controller < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        BaudRateEditField            matlab.ui.control.EditField
        BaudRateEditFieldLabel       matlab.ui.control.Label
        SerialPortEditField          matlab.ui.control.EditField
        SerialPortEditFieldLabel     matlab.ui.control.Label
        Lamp                         matlab.ui.control.Lamp
        Label                        matlab.ui.control.Label
        ConnectButton                matlab.ui.control.Button
        AutomaticReferencesCheckBox  matlab.ui.control.CheckBox
        REFEditField                 matlab.ui.control.NumericEditField
        REFEditFieldLabel            matlab.ui.control.Label
        adEditField                  matlab.ui.control.NumericEditField
        adEditFieldLabel             matlab.ui.control.Label
        TiEditField                  matlab.ui.control.NumericEditField
        TiEditFieldLabel             matlab.ui.control.Label
        KEditField                   matlab.ui.control.NumericEditField
        KEditFieldLabel              matlab.ui.control.Label
        UIAxes                       matlab.ui.control.UIAxes
    end

    properties (Access = private)
        SerialPort                      % Handle to serialport object
        t 
        y
        Plot
        Plot_Measure                            % Handle to "Plot View" line object
        Plot_Reference                            % Handle to "Plot View" line object
        P = 10
        I = 1
        D = 0
        REF = 0
        automatic_references 
        
   
    end
    methods (Access = private)
        
        function serialportCallback(app,obj,evt)
            %   serialportCallback serves as the callback when new data is
            %   received by the serialport object, as set by configureCallback
            %   This callback controls the app flow based on the app states

            % Handle Data state
            inputline = readline(obj)
            if strlength(inputline) > 50
                str_ = strsplit(inputline,"\t");
                time = str_(1);
                time = strsplit(time,":");
                time = str2double(time(2));
                app.t = [app.t ; time];
                ref = str_(6);
                ref = strsplit(ref,":");
                ref = str2double(ref(2));
                meas = str_(5);
                meas = strsplit(meas,":");
                meas = str2double(meas(2));
                app.y = [app.y ; ref meas];
                % Update Live View state
                set(app.Plot,"XData",app.t,"YData",app.y(:,1));
                set(app.Plot_Measure,"XData",app.t,"YData",app.y(:,2));
            end
        end

        
        function setAppState(app,state)
            %setAppState enables and disables buttons based on the state that
            %   the app is in

            switch state
                case "DeviceSelection"
                    % Only connect, baud rate, serial port, and file name
                    % select are enabled disable Start logging button
                    app.SerialPortEditField.Enable = "on";
                    app.BaudRateEditField.Enable = "on";
                    app.ConnectButton.Enable = "on";
                    app.ConnectButton.Text = "Connect";

                    app.Lamp.Color = [1.00,0.00,0.00]; %Red
                    %[0.00,1.00,0.00]; %Green
                    app.Plot = plot(app.UIAxes,0,0,'Color',"#0072BD");
                    hold(app.UIAxes);
                    app.Plot_Measure = plot(app.UIAxes,0,0,'Color',"#D95319");

                    ylim(app.UIAxes,"padded")
                    xlim(app.UIAxes,"padded")

                    app.TiEditField.Enable = "off";
                    app.KEditField.Enable = "off";
                    app.adEditField.Enable = "off";
                    app.REFEditField.Enable = "off";
                    app.AutomaticReferencesCheckBox.Enable = "off";

                    app.t = [];
                    app.y = [];
                    
                case "Connected"
                    % Enable everything, disable the "configuration"
                    % options (except for disconnect)
                    app.SerialPortEditField.Enable = "off";
                    app.BaudRateEditField.Enable = "off";
                    app.ConnectButton.Enable = "on";

                    app.ConnectButton.Text = "Disconnect";

                    app.Plot = plot(app.UIAxes,0,0,'Color',"#0072BD");
                    hold(app.UIAxes);
                    app.Plot_Measure = plot(app.UIAxes,0,0,'Color',"#D95319");

                    ylim(app.UIAxes,"padded")
                    xlim(app.UIAxes,"padded")

                    app.TiEditField.Enable = "on";
                    app.KEditField.Enable = "on";
                    app.adEditField.Enable = "on";
                    app.REFEditField.Enable = "on";
                    app.AutomaticReferencesCheckBox.Enable = "on";

                    app.Lamp.Color = [0.00,1.00,0.00]; %Red
                    app.t = [];
                    app.y = [];
                    %[0.00,1.00,0.00]; %Green
            end
        end
        

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: KEditField
        function KEditFieldValueChanged(app, event)
            app.P = app.KEditField.Value;
            to_send = sprintf("K:%f",app.P);
            writeline(app.SerialPort,to_send)
        end

        % Value changed function: TiEditField
        function TiEditFieldValueChanged(app, event)
            app.I = app.TiEditField.Value;
            to_send = sprintf("Ti:%f",app.I);
            writeline(app.SerialPort,to_send)
            
        end

        % Value changed function: adEditField
        function adEditFieldValueChanged(app, event)
            app.D = app.adEditField.Value;
            to_send = sprintf("ad:%f",app.D);
            writeline(app.SerialPort,to_send)

            
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)

            if strcmp(app.ConnectButton.Text, "Connect")
                setAppState(app,"Connected");
                drawnow
    
                % Validate selected serial port device
                try
                    app.SerialPort = serialport(app.SerialPortEditField.Value,str2double(app.BaudRateEditField.Value));
                    configureTerminator(app.SerialPort,"CR/LF")
                catch ME
                    uialert(app.UIFigure,ME.message,"Serialport Connection Error",Interpreter="html")
                    setAppState(app,"DeviceSelection");
                    return
                end
    
                % Set terminator and callback
                configureTerminator(app.SerialPort,"CR/LF")
                configureCallback(app.SerialPort,"terminator", @(obj,event) serialportCallback(app,obj,event))
   
            else
                setAppState(app,"DeviceSelection");
                app.SerialPort = [];
            end
        end

        % Callback function
        function RefreshButtonPushed(app, event)
             app.SerialPortEditField.Items = cellstr(["" serialportlist]);
        end

        % Value changed function: REFEditField
        function REFEditFieldValueChanged(app, event)
            app.REF = app.REFEditField.Value;
            to_send = sprintf("REF:%f",app.REF);
            writeline(app.SerialPort,to_send)
        end

        % Value changed function: AutomaticReferencesCheckBox
        function AutomaticReferencesCheckBoxValueChanged(app, event)
            app.automatic_references = app.AutomaticReferencesCheckBox.Value;
            to_send = sprintf("Auto:%d",app.automatic_references)
            writeline(app.SerialPort,to_send)
            if app.automatic_references
                 to_send = sprintf("REF:%f",0)
                 writeline(app.SerialPort,to_send);
                app.REFEditField.Enable = "off";
            else
                app.REFEditField.Enable = "on";
            end
            
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Controller Performance')
            xlabel(app.UIAxes, 'Time - [s]')
            ylabel(app.UIAxes, 'Motor speed - [rpm]')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.KEditFieldLabel = uilabel(app.UIFigure);
            app.KEditFieldLabel.Text = 'K';
            app.KEditField = uieditfield(app.UIFigure, 'numeric');
            app.KEditField.ValueChangedFcn = createCallbackFcn(app, @KEditFieldValueChanged, true);
            app.KEditField.Value = 10;
            app.TiEditFieldLabel = uilabel(app.UIFigure);
            app.TiEditFieldLabel.Text = 'Ti';
            app.TiEditField = uieditfield(app.UIFigure, 'numeric');
            app.TiEditField.ValueChangedFcn = createCallbackFcn(app, @TiEditFieldValueChanged, true);
            app.TiEditField.Value = 1;
            app.adEditFieldLabel = uilabel(app.UIFigure);
            app.adEditFieldLabel.Text = 'ad';
            app.adEditField = uieditfield(app.UIFigure, 'numeric');
            app.adEditField.ValueChangedFcn = createCallbackFcn(app, @adEditFieldValueChanged, true);
            app.REFEditFieldLabel = uilabel(app.UIFigure);
            app.REFEditFieldLabel.Text = 'REF';
            app.REFEditField = uieditfield(app.UIFigure, 'numeric');
            app.REFEditField.ValueChangedFcn = createCallbackFcn(app, @REFEditFieldValueChanged, true);
            app.AutomaticReferencesCheckBox = uicheckbox(app.UIFigure);
            app.AutomaticReferencesCheckBox.ValueChangedFcn = createCallbackFcn(app, @AutomaticReferencesCheckBoxValueChanged, true);
            app.AutomaticReferencesCheckBox.Text = 'Automatic References';
            app.AutomaticReferencesCheckBox.Value = true;
            app.ConnectButton = uibutton(app.UIFigure, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.Lamp = uilamp(app.UIFigure);
            app.SerialPortEditFieldLabel = uilabel(app.UIFigure);
            app.SerialPortEditFieldLabel.Text = 'Serial Port';
            app.SerialPortEditField = uieditfield(app.UIFigure, 'text');
            app.SerialPortEditField.Value = 'COM4';
            app.BaudRateEditFieldLabel = uilabel(app.UIFigure);
            app.BaudRateEditFieldLabel.Text = 'Baud Rate';
            app.BaudRateEditField = uieditfield(app.UIFigure, 'text');
            app.BaudRateEditField.Value = '57600';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Controller

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end